classdef IBL_Experiment_Control_UDPtesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EmergencyMenu             matlab.ui.container.Menu
        EnableallbuttonsMenu      matlab.ui.container.Menu
        EndALLButton              matlab.ui.control.Button
        EndBlockButton            matlab.ui.control.Button
        StartBlockButton          matlab.ui.control.Button
        StartNewExperimentButton  matlab.ui.control.Button
        AnimalNameDropDown        matlab.ui.control.DropDown
        AnimalNameDropDownLabel   matlab.ui.control.Label
    end


   properties (GetAccess = public)
        ExpRef = ''; % ExpRef of the currently running acquisition
    end

    properties (Access = private)
        udpScanImage; % a UDP object that will be used to send messages to ScanImage
        udpTimeline; % a UDP object that will be used to send messages to Timeline
        udpIBL; % a UDP object that will be used to comminucate with iblrig
        daqSession; % DAQ session for the NI board that will be used for triggering
        % ExpRef = ''; % ExpRef of the currently running acquisition
        lastExpRef = ''; % ExpRef of the last acquisition
    end

    methods (Access = private)
        
        function disableAll(app)
            app.AnimalNameDropDown.Enable = false;
            app.StartNewExperimentButton.Enable = false;
            app.StartBlockButton.Enable = false;
            app.EndBlockButton.Enable = false;
            app.EndALLButton.Enable = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.udpScanImage = udp(myIP, 1001); % automatic detection of IP for this computer
            app.udpScanImage = udp('128.40.198.175', 1001); % DESKTOP-AKP5ABA
            fopen(app.udpScanImage);
            app.udpTimeline = udp('128.40.198.166', 1001); % ZIGZAG previously 128.40.198.195
            fopen(app.udpTimeline);
            app.udpIBL = udp('0.0.0.0', 9999, 'LocalPort', 11002);
            app.udpIBL.UserData.app = app;
            set(app.udpIBL, 'DatagramReceivedFcn', @processIBLUDP);
            set(app.udpIBL, 'OutputBufferSize', 4096);
            set(app.udpIBL, 'InputBufferSize', 4096);
            fopen(app.udpIBL);
            app.daqSession = daq.createSession('ni');
            app.daqSession.addDigitalChannel('Dev1', 'port0/line0', 'OutputOnly');

        end

        % Drop down opening function: AnimalNameDropDown
        function AnimalNameDropDownOpening(app, event)
            subs = dat.listSubjects;
            %subs = {'...'; 'test'; 'SP048'; 'SP049'; 'SP052'; 'SP054'};
            subs = subs(startsWith(subs, 'SP'));
            subs = cat(1, {'...'; 'test'}, subs);
            app.AnimalNameDropDown.Items = subs;
        end

        % Value changed function: AnimalNameDropDown
        function AnimalNameDropDownValueChanged(app, event)
            value = app.AnimalNameDropDown.Value;
            fprintf('Animal changed to ''%s''\n', value);
            app.StartNewExperimentButton.Enable = true;
            app.StartBlockButton.Enable = false;
            app.EndBlockButton.Enable = false;
            app.EndALLButton.Enable = false;
        end

        % Button pushed function: StartNewExperimentButton
        function StartNewExperimentButtonPushed(app, event)
            disableAll(app); %prevent quick clicking
            animalName = app.AnimalNameDropDown.Value;
            [app.ExpRef, expSeq] = dat.newExp(animalName);
            [subject, iSeries, expNum] = dat.expRefToMpep(app.ExpRef);
            fprintf('Starting new experiment %s for all datahosts\n', app.ExpRef);
            expStartMessage = sprintf('ExpStart %s %s %s', subject, num2str(iSeries), num2str(expNum));
            fwrite(app.udpScanImage, expStartMessage);
            fwrite(app.udpTimeline, expStartMessage);
            blockStartMessage = sprintf('BlockStart %s %s %s', subject, num2str(iSeries), num2str(expNum));
            fwrite(app.udpTimeline, blockStartMessage);

            app.AnimalNameDropDown.Enable = false;
            app.StartNewExperimentButton.Enable = false;
            app.StartBlockButton.Enable = false;
            app.EndBlockButton.Enable = true;
            app.EndALLButton.Enable = false;
        end

        % Button pushed function: EndBlockButton
        function EndBlockButtonPushed(app, event)
            disableAll(app); %prevent quick clicking
            fprintf('Stopping current block %s\n', app.ExpRef);
            % send TTL to stop acquisition (after completing a volume)
            app.daqSession.outputSingleScan(0); % make sure there will be a rising edge
            pause(0.1);
            app.daqSession.outputSingleScan(1);
            pause(5); % wait enough time for the whole volume to finis before sending ExpEnd
            app.daqSession.outputSingleScan(0);
            [subject, iSeries, expNum] = dat.expRefToMpep(app.ExpRef);
            expEndMessage = sprintf('ExpEnd %s %s %s', subject, num2str(iSeries), num2str(expNum));
            fwrite(app.udpScanImage, expEndMessage);
            pause(5); % allow ScanImage to stop acquisition
            blockEndMessage = sprintf('BlockEnd %s %s %s', subject, num2str(iSeries), num2str(expNum));
            fwrite(app.udpTimeline, blockEndMessage);

            app.lastExpRef = app.ExpRef;
            % app.ExpRef = '';
            app.AnimalNameDropDown.Enable = false;
            app.StartNewExperimentButton.Enable = false;
            app.StartBlockButton.Enable = true;
            app.EndBlockButton.Enable = false;
            app.EndALLButton.Enable = true;
        end

        % Button pushed function: StartBlockButton
        function StartBlockButtonPushed(app, event)
            disableAll(app); %prevent quick clicking
            animalName = app.AnimalNameDropDown.Value;
            if isempty(app.ExpRef)
                [app.ExpRef, expSeq] = dat.newExp(animalName);
            end
            [subject, iSeries, expNum] = dat.expRefToMpep(app.ExpRef);
            fprintf('Starting new block %s\n', app.ExpRef);
            expStartMessage = sprintf('ExpStart %s %s %s', subject, num2str(iSeries), num2str(expNum));
            blockStartMessage = sprintf('BlockStart %s %s %s', subject, num2str(iSeries), num2str(expNum));
            fwrite(app.udpTimeline, blockStartMessage);
            pause(1); % Make sure neuralFrames start arriving only after BlockStart UDP on Timeline
            fwrite(app.udpScanImage, expStartMessage);
            pause(1); % Allow ScanImage to start Grabbing
            % the next UDP will tell ScanImage to not wait for the hardware
            % trigger, and start acquiring immediately (START ACQ button)
            fwrite(app.udpScanImage, blockStartMessage); 
            
            app.AnimalNameDropDown.Enable = false;
            app.StartNewExperimentButton.Enable = false;
            app.StartBlockButton.Enable = false;
            app.EndBlockButton.Enable = true;
            app.EndALLButton.Enable = false;            
        end

        % Button pushed function: EndALLButton
        function EndALLButtonPushed(app, event)
            disableAll(app); %prevent quick clicking
            fprintf('Stopping the experiment %s for all datahosts\n', app.lastExpRef);
            [subject, iSeries, expNum] = dat.expRefToMpep(app.lastExpRef);
            expEndMessage = sprintf('ExpEnd %s %s %s', subject, num2str(iSeries), num2str(expNum));
            expCompleteMessage = sprintf('ExpComplete %s %s %s', subject, num2str(iSeries), num2str(expNum));

            % ScanImage should already be stopped, so the next line just
            % requests a flag file generetaed for copyscript
            fwrite(app.udpScanImage, expCompleteMessage);
            % pause(1);

            % This BlockEnd was already sent
            % blockEndMessage = sprintf('BlockEnd %s %s %s', subject, num2str(iSeries), num2str(expNum));
            % fwrite(app.udpTimeline, blockEndMessage);
            % pause(1);

            % we only need to send ExpEnd to Timeline
            fwrite(app.udpTimeline, expEndMessage);

            app.lastExpRef = '';            
            app.ExpRef = '';
            app.AnimalNameDropDown.Enable = true;
            app.StartNewExperimentButton.Enable = true;
            app.StartBlockButton.Enable = false;
            app.EndBlockButton.Enable = false;
            app.EndALLButton.Enable = false;            
        end

        % Menu selected function: EnableallbuttonsMenu
        function EnableallbuttonsMenuSelected(app, event)
            app.AnimalNameDropDown.Enable = true;
            app.StartNewExperimentButton.Enable = true;
            app.StartBlockButton.Enable = true;
            app.EndBlockButton.Enable = true;
            app.EndALLButton.Enable = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.udpScanImage);
            fclose(app.udpTimeline);
            fclose(app.udpIBL);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EmergencyMenu = uimenu(app.UIFigure);
            app.EmergencyMenu.Text = 'Emergency';
            app.EnableallbuttonsMenu = uimenu(app.EmergencyMenu);
            app.EnableallbuttonsMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableallbuttonsMenuSelected, true);
            app.EnableallbuttonsMenu.Text = 'Enable all buttons';
            app.AnimalNameDropDownLabel = uilabel(app.UIFigure);
            app.AnimalNameDropDownLabel.Text = 'Animal Name';
            app.AnimalNameDropDown = uidropdown(app.UIFigure);
            app.AnimalNameDropDown.Items = {'...'};
            app.AnimalNameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AnimalNameDropDownOpening, true);
            app.AnimalNameDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalNameDropDownValueChanged, true);
            app.AnimalNameDropDown.Tooltip = {'Pick an animal from the list on the data server Subjects folder'};
            app.AnimalNameDropDown.Value = '...';
            app.StartNewExperimentButton = uibutton(app.UIFigure, 'push');
            app.StartNewExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewExperimentButtonPushed, true);
            app.StartNewExperimentButton.Enable = 'off';
            app.StartNewExperimentButton.Tooltip = {'ExpStart to ALL'};
            app.StartNewExperimentButton.Text = 'Start New Experiment';
            app.StartBlockButton = uibutton(app.UIFigure, 'push');
            app.StartBlockButton.ButtonPushedFcn = createCallbackFcn(app, @StartBlockButtonPushed, true);
            app.StartBlockButton.Enable = 'off';
            app.StartBlockButton.Tooltip = {'ExpStart to ScanImage'; 'BlockStart to ALL'};
            app.StartBlockButton.Text = 'Start Block';
            app.EndBlockButton = uibutton(app.UIFigure, 'push');
            app.EndBlockButton.ButtonPushedFcn = createCallbackFcn(app, @EndBlockButtonPushed, true);
            app.EndBlockButton.Enable = 'off';
            app.EndBlockButton.Tooltip = {'BlockEnd to ALL'; 'ExpEnd to ScanImage'};
            app.EndBlockButton.Text = 'End Block';
            app.EndALLButton = uibutton(app.UIFigure, 'push');
            app.EndALLButton.ButtonPushedFcn = createCallbackFcn(app, @EndALLButtonPushed, true);
            app.EndALLButton.Enable = 'off';
            app.EndALLButton.Tooltip = {'ExpEnd to ALL'};
            app.EndALLButton.Text = 'End ALL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IBL_Experiment_Control_UDPtesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end